classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        CheckBox_3  matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        CheckBox    matlab.ui.control.CheckBox
        Label_5     matlab.ui.control.Label
        Format      matlab.ui.container.ButtonGroup
        Population  matlab.ui.control.RadioButton
        Biomassa    matlab.ui.control.RadioButton
        Stations    matlab.ui.control.DropDown
        Label_4     matlab.ui.control.Label
        Target      matlab.ui.control.NumericEditField
        Label_3     matlab.ui.control.Label
        loadBurton  matlab.ui.control.Button
        Time        matlab.ui.control.NumericEditField
        Label       matlab.ui.control.Label
        plotButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Data
        time_stations
        col
        range
        labely
        graph
        date
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.Label_5.Text = 'Программа ищет решения';
            selectedButton = app.Format.SelectedObject;
            if selectedButton == app.Biomassa
                app.col = 2;
                app.labely = app.Biomassa.Text;
                type = 1;
            else
                app.col = 1;
                type = 0;
                app.labely = app.Population.Text;
            end
            value = app.Stations.Value;
            for i=1:3:length(app.time_stations)
                if strcmp(value, app.time_stations(i))
                    app.range = i;
                end
            end
            xc = get(app.Target, 'Value');
            app.UIAxes.YLabel.String = app.labely;
            x1 = app.Data(app.range:app.range+2, app.col);
            x2 = app.Data(app.range:app.range+2, app.col + 2);
            N = get(app.Time, 'Value');
            data = plot_system_full(N, x1, x2, xc, app.date(app.range:app.range+2));
            if ischar(data)
                app.Label_5.Text = data;
            else
                app.graph = plot(app.UIAxes, 0:0.01:N, data);
                app.Label_5.Text = 'Решение системы на графике';
                app.UIAxes.XLim = [0 N];
            end
        end

        % Button pushed function: loadBurton
        function loadBurtonPushed(app, event)
            FILE = uigetfile("*.xlsx");
            app.Data = parse_excel(FILE);
            app.date = parse_date(FILE);
            app.time_stations = parse_stations(FILE);
            app.Time.Value = app.date(3);
            rows = length(app.time_stations);
            app.Stations.Items = app.time_stations(1:3:rows);
            app.Label_5.Text = 'Данные успешно загружены';
        end

        % Value changed function: Stations
        function StationsValueChanged(app, event)
            value = app.Stations.Value;
            for i=1:3:size(app.time_stations, 1)
                if strcmp(value, app.time_stations(i,2))
                    app.Time.Value = app.date(i + 2);
                end
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.graph(1,:).Visible = 'on';
            else
                app.graph(1,:).Visible = 'off';
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
             value = app.CheckBox_2.Value;
            if value == 1
                app.graph(2,:).Visible = 'on';
            else
                app.graph(2,:).Visible = 'off';
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
             value = app.CheckBox_3.Value;
            if value == 1
                app.graph(3,:).Visible = 'on';
            else
                app.graph(3,:).Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Модель "хищник-жертва с питанием"')
            xlabel(app.UIAxes, 'Время, дни')
            ylabel(app.UIAxes, 'Y')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'Построить график';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Время';
            app.Time = uieditfield(app.UIFigure, 'numeric');
            app.loadBurton = uibutton(app.UIFigure, 'push');
            app.loadBurton.ButtonPushedFcn = createCallbackFcn(app, @loadBurtonPushed, true);
            app.loadBurton.Text = 'Загрузить данные';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Целевое значение';
            app.Target = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Станция';
            app.Stations = uidropdown(app.UIFigure);
            app.Stations.ValueChangedFcn = createCallbackFcn(app, @StationsValueChanged, true);
            app.Format = uibuttongroup(app.UIFigure);
            app.Format.Title = 'Формат исходных данных';
            app.Biomassa = uiradiobutton(app.Format);
            app.Biomassa.Text = 'Биомасса (мг/м3)';
            app.Biomassa.Value = true;
            app.Population = uiradiobutton(app.Format);
            app.Population.Text = 'Численность (экз/л)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Фитопланктон';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = 'Зоопланктон';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = 'Питание';
            app.CheckBox_3.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end